classdef RLGridworldApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridworldButtonGroup            matlab.ui.container.ButtonGroup
        Button9                         matlab.ui.control.ToggleButton
        Button25                        matlab.ui.control.ToggleButton
        InitializeButton                matlab.ui.control.Button
        QLearningParametersPanel        matlab.ui.container.Panel
        greedyEditFieldLabel            matlab.ui.control.Label
        greedyEditField                 matlab.ui.control.NumericEditField
        discountLabel                   matlab.ui.control.Label
        discountEditField               matlab.ui.control.NumericEditField
        TrainingControlPanel            matlab.ui.container.Panel
        SingleStepButton                matlab.ui.control.Button
        AutoTrainButton                 matlab.ui.control.StateButton
        ManualcontrolPanel              matlab.ui.container.Panel
        NButton                         matlab.ui.control.Button
        EButton                         matlab.ui.control.Button
        WButton                         matlab.ui.control.Button
        SButton                         matlab.ui.control.Button
        bypassgreedypolicyLabel         matlab.ui.control.Label
        SimulatePanel                   matlab.ui.container.Panel
        SimulateButton                  matlab.ui.control.StateButton
        runcurrentgreedypolicywithoutupdatingQValuesLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxesGW                        matlab.ui.control.UIAxes
        UIAxesQ                         matlab.ui.control.UIAxes
        UIAxesP                         matlab.ui.control.UIAxes
        QValueCalculationPanel          matlab.ui.container.Panel
        QValueCalculationEditField      matlab.ui.control.EditField
        QsarewardssadiscountmaxQsLabel  matlab.ui.control.Label
        UpdateruleLabel                 matlab.ui.control.Label
        greedyPolicyPanel               matlab.ui.container.Panel
        ExploitationLampLabel           matlab.ui.control.Label
        ExploitationLamp                matlab.ui.control.Lamp
        ExplorationLampLabel            matlab.ui.control.Label
        ExplorationLamp                 matlab.ui.control.Lamp
        RandomActionLabel               matlab.ui.control.Label
        GreedyActionLabel               matlab.ui.control.Label
        RewardsLabel                    matlab.ui.control.Label
        TrainingProgressPanel           matlab.ui.container.Panel
        AverageRewardEditFieldLabel     matlab.ui.control.Label
        AverageRewardEditField          matlab.ui.control.NumericEditField
        StepCounterEditFieldLabel       matlab.ui.control.Label
        StepCounterEditField            matlab.ui.control.NumericEditField
        InfoLabelGW                     matlab.ui.control.Label
        InfoLabelQ                      matlab.ui.control.Label
        InfoLabelP                      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        NrStates = 3 % Nr Of States per dimension
        NrActions = 4 % Nr of Actions
        GW = zeros(3) % Gridworld
        qtable = zeros(3, 3, 4) % qtable
        R = zeros(3^2)
        stepPenaltyReward = -1 % Description
        moveForwardReward = 10
        moveBackwardReward = -30
        s2i = reshape(1:9,3,3)' % Description
        startstate = [2 2]
        agent % agent marker for plotting in GW
        state % current state of the agent in GW
        discountFactor = 0.95
        epsilon = 0.1
        rewards = []; % Description
    end
    
    methods (Access = private)
        
        
        function trainstep(app)
            action = egreedy(app);
            newstate = move(app, action);
            reward = app.R(app.s2i(app.state(1),app.state(2)), app.s2i(newstate(1),newstate(2)));
            app.rewards = [reward, app.rewards(1:end-1)];
            app.AverageRewardEditField.Value = sum(app.rewards)/length(app.rewards);
            
            % Q-Learning
            qvalcalctext(app, newstate, reward, action) % Text output
            app.qtable(app.state(1),app.state(2),action) = reward + app.discountFactor * max(app.qtable(newstate(1),newstate(2),:));
            
            %%% Plotting:
            %% Update agent in GW
            app.agent.XData=newstate(2);
            app.agent.YData=newstate(1);
            
            %% Plot Q-table
            plotQtable(app, newstate)
            
            %% Plot Policy
            plotPolicy(app)
            
            app.StepCounterEditField.Value = app.StepCounterEditField.Value + 1;
            app.state = newstate;
        end
        
        function manualTrainStep(app, action)
            newstate = move(app, action);
            reward = app.R(app.s2i(app.state(1),app.state(2)), app.s2i(newstate(1),newstate(2)));
            app.rewards = [reward, app.rewards(1:end-1)];
            app.AverageRewardEditField.Value = sum(app.rewards)/length(app.rewards);
            
            % Q-Learning
            qvalcalctext(app, newstate, reward, action) % Text output
            app.qtable(app.state(1),app.state(2),action) = reward + app.discountFactor * max(app.qtable(newstate(1),newstate(2),:));
            
            %%% Plotting:
            %% Update agent in GW
            app.agent.XData=newstate(2);
            app.agent.YData=newstate(1);
            
            %% Plot Q-table
            plotQtable(app, newstate)
            
            %% Plot Policy
            plotPolicy(app)
            
            app.StepCounterEditField.Value = app.StepCounterEditField.Value + 1;
            app.state = newstate;
        end
        
        function action = egreedy(app)
            if rand < app.epsilon
                action = randi(4,1);
                app.ExplorationLamp.Color = [0, 1, 0];
                app.ExploitationLamp.Color = [0.3, 0.3, 0.3];
            else
                [maxq, maxqidx] = max(app.qtable(app.state(1),app.state(2),:));
                app.ExplorationLamp.Color = [0.3, 0.3, 0.3];
                app.ExploitationLamp.Color = [0, 1, 0];
                
                if length(maxqidx) > 1
                    action = maxqidx(randi(length(maxqidx),1));
                else
                    action = maxqidx;
                end
            end
        end
        
        
        function newstate = move(app,action)
            %Move one step in gridworld according to action
            % North = 1, South = 2, East = 3, West = 4
            switch action
                case 1 %North
                    if app.state(1) > 1
                        newstate = app.state + [-1, 0];
                    else
                        newstate = app.state;
                    end
                    
                case 2 %South
                    if app.state(1) < app.NrStates
                        newstate = app.state + [1, 0];
                    else
                        newstate = app.state;
                    end
                    
                case 3 %East
                    if app.state(2) < app.NrStates
                        newstate = app.state + [0, 1];
                    else
                        newstate = app.state;
                    end
                    
                case 4 %West
                    if app.state(2) > 1
                        newstate = app.state + [0, -1];
                    else
                        newstate = app.state;
                    end
            end
        end
        
        function plotgrid(app, UIaxes)
            %horizontal lines
            linevec=0.5:app.NrStates+0.5;
            for j=1:app.NrStates+1
                line(UIaxes,0.5:app.NrStates+0.5, linevec(j)*ones(1,app.NrStates+1), 'Color', 'k', 'LineWidth', 2)
            end
            %vertical lines
            for j=1:app.NrStates+1
                line(UIaxes,linevec(j)*ones(1,app.NrStates+1), 0.5:app.NrStates+0.5, 'Color', 'k', 'LineWidth', 2)
            end
        end
        
        function plotQtable(app, newstate)
            hold(app.UIAxesQ,"off")
            imagesc(app.UIAxesQ, zeros(app.NrStates));
            axis(app.UIAxesQ, "image");
            app.UIAxesQ.XTick=(1:app.NrStates);
            app.UIAxesQ.YTick=(1:app.NrStates);
            plotgrid(app, app.UIAxesQ)
            hold(app.UIAxesQ,"on")
            plot(app.UIAxesQ,newstate(2),newstate(1),'o','MarkerFaceColor','r','MarkerSize',15);
            
            makebox = @(state)[state(1)-0.33 state(1)+0.33 state(2)+0.33 state(2)-0.33];
            for horz=1:app.NrStates
                for vert=1:app.NrStates
                    box = makebox([vert, horz]);
                    
                    for actns = 3:4 %horizontal rewards
                        text(app.UIAxesQ, box(actns),vert,['\bf ' num2str(round(app.qtable(vert,horz,actns),3,'significant'))], 'HorizontalAlignment','center', 'Color', 'k')
                    end
                    for actns = 1:2 %horizontal rewards
                        text(app.UIAxesQ, horz,box(actns),['\bf ' num2str(round(app.qtable(vert,horz,actns),3,'significant'))], 'HorizontalAlignment','center', 'Color', 'k')
                    end
                end
            end
        end
        
        
        function plotPolicy(app)
            hold(app.UIAxesP,"off")
            [qmax, qmaxidx] = max(app.qtable,[],3);
            imagesc(app.UIAxesP, qmaxidx);
            hold(app.UIAxesP,"on")
            axis(app.UIAxesP, "image");
            app.UIAxesP.XTick=(1:app.NrStates);
            app.UIAxesP.YTick=(1:app.NrStates);
            plotgrid(app, app.UIAxesP);
            
            symbols = ['^', 'v', '>', '<'];
            for horz=1:app.NrStates
                for vert=1:app.NrStates
                    plot(app.UIAxesP, vert,horz,symbols(qmaxidx(horz,vert)), 'MarkerSize',12.5, 'MarkerFaceColor', 'k', 'MarkerEdgeColor', 'k');
                end
            end
        end
        
        
        function plotRewards(app)
            makebox = @(state)[state(1)+0.33 state(1)-0.33 state(2)+0.33 state(2)-0.33];
            plusminusidx = [-1 1 1 -1]; %[north south east west]
            for horz=1:app.NrStates
                for vert=1:app.NrStates
                    box = makebox([vert, horz]);
                    
                    for actns = 3:4 %horizontal rewards
                        if (horz + plusminusidx(actns)) >= 1 && (horz + plusminusidx(actns)) <= app.NrStates
                            text(app.UIAxesGW, box(actns),vert,['\bf ' num2str(app.R(app.s2i(vert,horz),app.s2i(vert,horz + plusminusidx(actns))))], 'HorizontalAlignment','center', 'Color', [0.6350 0.0780 0.1840])
                        else
                            text(app.UIAxesGW, box(actns),vert,['\bf ' num2str(app.R(app.s2i(vert,horz),app.s2i(vert,horz)))], 'HorizontalAlignment','center', 'Color', [0.6350 0.0780 0.1840])
                        end
                    end
                    
                    for actns = 1:2 %vertical rewards
                        if (vert + plusminusidx(actns)) >= 1 && (vert + plusminusidx(actns)) <= app.NrStates
                            text(app.UIAxesGW, horz,box(actns),['\bf ' num2str(app.R(app.s2i(vert,horz),app.s2i(vert + plusminusidx(actns),horz)))], 'HorizontalAlignment','center', 'Color', [0.6350 0.0780 0.1840])
                        else
                            text(app.UIAxesGW, horz,box(actns),['\bf ' num2str(app.R(app.s2i(vert,horz),app.s2i(vert,horz)))], 'HorizontalAlignment','center', 'Color', [0.6350 0.0780 0.1840])
                        end
                    end
                end
            end
        end


        function qvalcalctext(app, newstate, reward, action)
            actnNames = ['N' 'S' 'E' 'W'];
            app.QValueCalculationEditField.Value = ['Q([' num2str(app.state(1)) ',' num2str(app.state(2)) ']'  ', ' actnNames(action) ')' ' =  ' num2str(reward) ' + ' num2str(app.discountFactor) ' * ' num2str(max(app.qtable(newstate(1),newstate(2),:)))];
        end
        
        function simstep(app)
            action = egreedy(app);
            newstate = move(app, action);
            reward = app.R(app.s2i(app.state(1),app.state(2)), app.s2i(newstate(1),newstate(2)));
            app.rewards = [reward, app.rewards(1:end-1)];
            app.AverageRewardEditField.Value = sum(app.rewards)/length(app.rewards);
            
            %%% Plotting:
            %% Update agent in GW
            app.agent.XData=newstate(2);
            app.agent.YData=newstate(1);
            
            %% Plot Q-table
            plotQtable(app, newstate)
            
            app.state = newstate;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitializeButtonPushed(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {860, 860};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {168, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            if app.Button9.Value == true
                app.NrStates = 3;
                app.s2i = reshape(1:9,3,3)';
                app.R = app.stepPenaltyReward*ones(app.NrStates^2);
                % moving forward
                app.R(app.s2i(3,3),app.s2i(3,2)) = app.moveForwardReward;
                app.R(app.s2i(3,2),app.s2i(3,1)) = app.moveForwardReward;
                %moving backward
                app.R(app.s2i(3,2),app.s2i(3,3)) = app.moveBackwardReward;
                app.R(app.s2i(3,1),app.s2i(3,2)) = app.moveBackwardReward;
                app.startstate = [2 2];
                                
            elseif app.Button25.Value == true
                app.NrStates = 5;
                app.s2i = reshape(1:25,5,5)';
                app.R = app.stepPenaltyReward*ones(app.NrStates^2);
                % moving forward
                app.R(app.s2i(5,5),app.s2i(5,4)) = app.moveForwardReward;
                app.R(app.s2i(5,4),app.s2i(5,3)) = app.moveForwardReward;
                app.R(app.s2i(5,3),app.s2i(5,2)) = app.moveForwardReward;
                app.R(app.s2i(5,2),app.s2i(5,1)) = app.moveForwardReward;
                %moving backward
                app.R(app.s2i(5,4),app.s2i(5,5)) = app.moveBackwardReward;
                app.R(app.s2i(5,3),app.s2i(5,4)) = app.moveBackwardReward;
                app.R(app.s2i(5,2),app.s2i(5,3)) = app.moveBackwardReward;
                app.R(app.s2i(5,1),app.s2i(5,2)) = app.moveBackwardReward;
                app.startstate = [3 3];
            end
            app.state = app.startstate;
            app.GW = zeros(app.NrStates);
            app.qtable = zeros(app.NrStates,app.NrStates,app.NrActions);
            app.StepCounterEditField.Value = 0;
            app.rewards = zeros(1,2*(app.NrStates^2));
            
            %% Plot Gridworld
            hold(app.UIAxesGW,"off")
            imagesc(app.UIAxesGW, app.GW)
            app.UIAxesGW.XTick=(1:app.NrStates);
            app.UIAxesGW.YTick=(1:app.NrStates);
            axis(app.UIAxesGW, "image");
            hold(app.UIAxesGW,"on")
            plotgrid(app, app.UIAxesGW);
            % plot indices
            StateNumber = 1;
            for steps=1:app.NrStates
                for vert=1:app.NrStates
                    text(app.UIAxesGW,vert,steps,num2str(StateNumber), 'HorizontalAlignment','center')
                    StateNumber = StateNumber + 1;
                end
            end
            plotRewards(app)
            app.agent=plot(app.UIAxesGW,app.startstate(2),app.startstate(1),'o','MarkerFaceColor','r','MarkerSize',15);
            disableDefaultInteractivity(app.UIAxesGW)
            
            %% Plot Q-table
            plotQtable(app, app.startstate)
            disableDefaultInteractivity(app.UIAxesQ)
            
            %% Plot Policy
            plotPolicy(app)
            disableDefaultInteractivity(app.UIAxesP)
        end

        % Value changed function: discountEditField
        function discountEditFieldValueChanged(app, event)
            app.discountFactor = app.discountEditField.Value;
        end

        % Value changed function: greedyEditField
        function greedyEditFieldValueChanged(app, event)
            app.epsilon = app.greedyEditField.Value;
        end

        % Button pushed function: SingleStepButton
        function SingleStepButtonPushed(app, event)
            trainstep(app)
        end

        % Value changed function: AutoTrainButton
        function AutoTrainButtonValueChanged(app, event)
            autotrain = app.AutoTrainButton.Value;
            if autotrain == true
                app.ManualcontrolPanel.Enable = 'off';
                app.GridworldButtonGroup.Enable = 'off';
                app.QLearningParametersPanel.Enable = 'off';
                app.SingleStepButton.Enable = 'off';
            else
                app.ManualcontrolPanel.Enable = 'on';
                app.GridworldButtonGroup.Enable = 'on';
                app.QLearningParametersPanel.Enable = 'on';
                app.SingleStepButton.Enable = 'on';
            end
            
            while autotrain == true
                trainstep(app)
                autotrain = app.AutoTrainButton.Value;
                drawnow
            end
            
        end

        % Button pushed function: NButton
        function NButtonPushed(app, event)
            manualTrainStep(app, 1)
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            manualTrainStep(app, 2)
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            manualTrainStep(app, 3)
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            manualTrainStep(app, 4)
        end

        % Value changed function: SimulateButton
        function SimulateButtonValueChanged(app, event)
            simulate = app.SimulateButton.Value;
            
            if simulate == true
                app.ManualcontrolPanel.Enable = 'off';
                app.GridworldButtonGroup.Enable = 'off';
                app.QLearningParametersPanel.Enable = 'off';
                app.TrainingControlPanel.Enable = 'off';
            else
                app.ManualcontrolPanel.Enable = 'on';
                app.GridworldButtonGroup.Enable = 'on';
                app.QLearningParametersPanel.Enable = 'on';
                app.TrainingControlPanel.Enable = 'on';
            end
            
            while simulate == true
                simstep(app)
                simulate = app.SimulateButton.Value;
                drawnow
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridworldButtonGroup = uibuttongroup(app.LeftPanel);
            app.GridworldButtonGroup.Tooltip = {'Initialize the Gridworld environment with either 9 or 25 states.'; 'Initalizing will reset all training progress.'};
            app.GridworldButtonGroup.Title = 'Gridworld';
            app.Button9 = uitogglebutton(app.GridworldButtonGroup);
            app.Button9.Text = '9 States';
            app.Button9.Value = true;
            app.Button25 = uitogglebutton(app.GridworldButtonGroup);
            app.Button25.Text = '25 States';
            app.InitializeButton = uibutton(app.GridworldButtonGroup, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Tooltip = {'Initialize the Gridworld environment with either 9 or 25 states.'; 'Initalizing will reset all training progress.'};
            app.InitializeButton.Text = 'Initialize';
            app.QLearningParametersPanel = uipanel(app.LeftPanel);
            app.QLearningParametersPanel.Title = 'Q-Learning Parameters';
            app.greedyEditFieldLabel = uilabel(app.QLearningParametersPanel);
            app.greedyEditFieldLabel.Text = 'ϵ-greedy';
            app.greedyEditField = uieditfield(app.QLearningParametersPanel, 'numeric');
            app.greedyEditField.Limits = [0 1];
            app.greedyEditField.ValueChangedFcn = createCallbackFcn(app, @greedyEditFieldValueChanged, true);
            app.greedyEditField.Tooltip = {'ϵ controls the greediness of your ϵ-greedy policy. ϵ close to zero means high greediness, ϵ close to one means high randomness/exploration.'};
            app.greedyEditField.Value = 0.1;
            app.discountLabel = uilabel(app.QLearningParametersPanel);
            app.discountLabel.Text = 'discount ɣ';
            app.discountEditField = uieditfield(app.QLearningParametersPanel, 'numeric');
            app.discountEditField.Limits = [0 1];
            app.discountEditField.ValueChangedFcn = createCallbackFcn(app, @discountEditFieldValueChanged, true);
            app.discountEditField.Tooltip = {'Discount factor for the calculation of Q-Values. Balances immediate rewards with expected sum of future rewards.'};
            app.discountEditField.Value = 0.9;
            app.TrainingControlPanel = uipanel(app.LeftPanel);
            app.TrainingControlPanel.Title = 'Training Control';
            app.SingleStepButton = uibutton(app.TrainingControlPanel, 'push');
            app.SingleStepButton.ButtonPushedFcn = createCallbackFcn(app, @SingleStepButtonPushed, true);
            app.SingleStepButton.Tooltip = {'Perform a single Q-learning step. Following your current ϵ-greedy policy.'};
            app.SingleStepButton.Text = 'Single Step';
            app.AutoTrainButton = uibutton(app.TrainingControlPanel, 'state');
            app.AutoTrainButton.ValueChangedFcn = createCallbackFcn(app, @AutoTrainButtonValueChanged, true);
            app.AutoTrainButton.Tooltip = {'Iteratively performs Q-Learning steps until button is pressed again.'; 'Follows the current ϵ-greedy policy.'};
            app.AutoTrainButton.Text = 'Auto-Train';
            app.ManualcontrolPanel = uipanel(app.TrainingControlPanel);
            app.ManualcontrolPanel.Tooltip = {'With these controls you can move the agent yourself through the gridworld. You can take the actions that you think are the best.'; 'This functionality can be used to show how a human with knowledge about the task can initialize the agents experience with demonstrations. The agent does not have to start from zero then, but already has a rough idea how to solve the task.'};
            app.ManualcontrolPanel.Title = 'Manual control';
            app.NButton = uibutton(app.ManualcontrolPanel, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.NButton.Text = 'N';
            app.EButton = uibutton(app.ManualcontrolPanel, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.WButton = uibutton(app.ManualcontrolPanel, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.SButton = uibutton(app.ManualcontrolPanel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.bypassgreedypolicyLabel = uilabel(app.ManualcontrolPanel);
            app.bypassgreedypolicyLabel.Text = 'bypass ϵ-greedy policy';
            app.SimulatePanel = uipanel(app.LeftPanel);
            app.SimulatePanel.Title = 'Simulate';
            app.SimulateButton = uibutton(app.SimulatePanel, 'state');
            app.SimulateButton.ValueChangedFcn = createCallbackFcn(app, @SimulateButtonValueChanged, true);
            app.SimulateButton.Text = 'Simulate';
            app.runcurrentgreedypolicywithoutupdatingQValuesLabel = uilabel(app.SimulatePanel);
            app.runcurrentgreedypolicywithoutupdatingQValuesLabel.Text = 'run current ϵ-greedy policy without updating Q-Values ';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesGW = uiaxes(app.RightPanel);
            title(app.UIAxesGW, 'Gridworld')
            xlabel(app.UIAxesGW, 's2')
            ylabel(app.UIAxesGW, 's1')
            zlabel(app.UIAxesGW, 'Z')
            app.UIAxesQ = uiaxes(app.RightPanel);
            title(app.UIAxesQ, 'Q-Values')
            xlabel(app.UIAxesQ, 's2')
            ylabel(app.UIAxesQ, 's1')
            zlabel(app.UIAxesQ, 'Z')
            app.UIAxesP = uiaxes(app.RightPanel);
            title(app.UIAxesP, 'Policy (greedy actions)')
            xlabel(app.UIAxesP, 's2')
            ylabel(app.UIAxesP, 's1')
            zlabel(app.UIAxesP, 'Z')
            app.QValueCalculationPanel = uipanel(app.RightPanel);
            app.QValueCalculationPanel.Title = 'Q-Value Calculation';
            app.QValueCalculationEditField = uieditfield(app.QValueCalculationPanel, 'text');
            app.QValueCalculationEditField.Editable = 'off';
            app.QsarewardssadiscountmaxQsLabel = uilabel(app.QValueCalculationPanel);
            app.QsarewardssadiscountmaxQsLabel.Text = 'Q(s,a) = reward(s,s'',a) + discount * max(Q(s'', : ))';
            app.UpdateruleLabel = uilabel(app.QValueCalculationPanel);
            app.UpdateruleLabel.Text = 'Update rule:';
            app.greedyPolicyPanel = uipanel(app.RightPanel);
            app.greedyPolicyPanel.Tooltip = {'Indicators to show if recent action was a greedy or random choice.'};
            app.greedyPolicyPanel.Title = 'ϵ-greedy Policy';
            app.ExploitationLampLabel = uilabel(app.greedyPolicyPanel);
            app.ExploitationLampLabel.Text = 'Exploitation';
            app.ExploitationLamp = uilamp(app.greedyPolicyPanel);
            app.ExplorationLampLabel = uilabel(app.greedyPolicyPanel);
            app.ExplorationLampLabel.Text = 'Exploration';
            app.ExplorationLamp = uilamp(app.greedyPolicyPanel);
            app.RandomActionLabel = uilabel(app.greedyPolicyPanel);
            app.RandomActionLabel.Text = 'Random Action';
            app.GreedyActionLabel = uilabel(app.greedyPolicyPanel);
            app.GreedyActionLabel.Text = 'Greedy Action';
            app.RewardsLabel = uilabel(app.RightPanel);
            app.RewardsLabel.Tooltip = {''};
            app.RewardsLabel.Text = 'Rewards';
            app.TrainingProgressPanel = uipanel(app.RightPanel);
            app.TrainingProgressPanel.Tooltip = {'Number of Q-Learning steps performed so far.'; 'Average reward per step in the gridworld. Calculated from a sliding window of previous rewards. Windowsize: 18 (9 States) and 50 (25 States).'};
            app.TrainingProgressPanel.Title = 'Training Progress';
            app.AverageRewardEditFieldLabel = uilabel(app.TrainingProgressPanel);
            app.AverageRewardEditFieldLabel.Text = 'Average Reward';
            app.AverageRewardEditField = uieditfield(app.TrainingProgressPanel, 'numeric');
            app.AverageRewardEditField.Editable = 'off';
            app.StepCounterEditFieldLabel = uilabel(app.TrainingProgressPanel);
            app.StepCounterEditFieldLabel.Text = 'Step Counter';
            app.StepCounterEditField = uieditfield(app.TrainingProgressPanel, 'numeric');
            app.StepCounterEditField.Editable = 'off';
            app.InfoLabelGW = uilabel(app.RightPanel);
            app.InfoLabelGW.Tooltip = {'You (the agent) are the red dot in the Gridworld. The red numbers are the rewards, indicating how good or bad your actions are. Your goal is to collect as much positive reward as possible. You have to consider the rewards as unknown, you can only observe the reward you receive for  your current action. Therefore you will have to do some exploration to figure them out. The experience you gather while exploring the Gridworld are stored in Q-Values. (see Q-values)'};
            app.InfoLabelGW.Text = 'Info';
            app.InfoLabelQ = uilabel(app.RightPanel);
            app.InfoLabelQ.Tooltip = {'Plot of your current Q-Table. The Q-table shows you which action in which state is best (highest Q-value) based on your experience from moving around in the Gridworld. Q-Values indicate the ''Q''uality of actions, see below how they are calculated.'; 'The Q-values actually represent the expected discounted future reward you get when taking this action in this state and thereafter follow your current policy. (See Policy)'};
            app.InfoLabelQ.Text = 'Info';
            app.InfoLabelP = uilabel(app.RightPanel);
            app.InfoLabelP.Tooltip = {'A plot of your current greedy policy. Arrows indicate the greedy action in each state. Following a greedy policy means taking always the action with the highest Q-Value in each state.'; 'Since you need the explore the whole gridworld to find an optimal policy, you cannot follow a purely greedy policy - especially at the beginning of training. This is why a ϵ-greedy policy is used, which means that once in a while random actions are performed. How often that happens is controlled by ϵ: the closer to 1 the more exploration, the closer to zero the more greediness. '};
            app.InfoLabelP.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RLGridworldApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end